classdef BurglarAlarmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_3                matlab.ui.container.Panel
        SerialStatusLabel      matlab.ui.control.Label
        BtnDisconnect          matlab.ui.control.Button
        BtnConnect             matlab.ui.control.Button
        COMPortField           matlab.ui.control.EditField
        EditFieldLabel         matlab.ui.control.Label
        COMPortLabel           matlab.ui.control.Label
        SERIALCONNECTIONLabel  matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        BtnBack                matlab.ui.control.Button
        BtnEnter               matlab.ui.control.Button
        Btn0                   matlab.ui.control.Button
        BtnClear               matlab.ui.control.Button
        Btn9                   matlab.ui.control.Button
        Btn8                   matlab.ui.control.Button
        Btn7                   matlab.ui.control.Button
        Btn6                   matlab.ui.control.Button
        Btn5                   matlab.ui.control.Button
        Btn4                   matlab.ui.control.Button
        Btn3                   matlab.ui.control.Button
        Btn2                   matlab.ui.control.Button
        Btn1                   matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        PINDotsLabel           matlab.ui.control.Label
        DisplayLabel           matlab.ui.control.Label
        FaceStatusLabel        matlab.ui.control.Label
        SystemStateLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
    SerialObj
    IsConnected  = false
    SerialTimer
    StatusTimer
    CurrentPIN   = ''
    SystemState  = 'DISCONNECTED'
    StatusFile   = ''
    CommandFile  = ''
    PINFile = ''
    GracePeriodFile = ''
    NewPIN = ''
    CurrentMode = 'PIN';      
    ClearPressTime
    ClearHoldTimer
    IsHoldingClear = false;
    maxLen = 4;
    SensorList = {}
    SensorCount = 0
    LogLines = {}
    OldPIN = ''
    end
    
    methods (Access = private)
     
         function sendToArduino(app, msg)
            if app.IsConnected
                try writeline(app.SerialObj, msg); catch; end
            end
         end

        function startTimers(app)
            app.SerialTimer               = timer();
            app.SerialTimer.Period        = 0.2;
            app.SerialTimer.ExecutionMode = 'fixedRate';
            app.SerialTimer.TimerFcn      = @(~,~) app.pollSerial();
            start(app.SerialTimer);
        
            app.StatusTimer               = timer();
            app.StatusTimer.Period        = 0.5;
            app.StatusTimer.ExecutionMode = 'fixedRate';
            app.StatusTimer.TimerFcn      = @(~,~) app.pollStatusFile();
            start(app.StatusTimer);
        end

        function setMode(app, mode)
            app.CurrentMode = mode;

            % Reset input whenever mode changes
            app.CurrentPIN = '';
            app.updatePINDots();

            switch mode
                case 'PIN'
                    app.updateDisplay('Enter 4-digit PIN');

                case 'MENU'
                    app.updateDisplay('Select an option:');
                    app.sendToArduino('CMD:MENU');

                case 'ACTION'
                    % handled elsewhere

                case 'SENSOR'
                    app.maxLen = 1;
                    % display handled by SENSOR_LIST messages

                case 'LOGS'
                    app.maxLen = 0;  % no input needed in logs view

                case 'CHANGE_PIN'
                    app.maxLen = 4;
                    %app.updateDisplay('Enter current PIN:');

                case 'GRACE_PERIOD'
                    app.maxLen = 2;  % allow up to 99 seconds
                    app.updateDisplay('Enter grace period (seconds):');
            end
        end

        function stopTimers(app)
            timers = {app.SerialTimer, app.StatusTimer};
            for i = 1:numel(timers)
                try
                    if ~isempty(timers{i})
                        stop(timers{i});
                        delete(timers{i});
                    end
                catch 
                end
            end
        end

        function pollSerial(app)
            if ~app.IsConnected; return; end
            try
                while app.SerialObj.NumBytesAvailable > 0
                    line = strtrim(readline(app.SerialObj));
                    if ~isempty(line)
                        app.handleArduinoMessage(line);
                    end
                end
            catch e
                app.updateDisplay(['Error: ' e.message]);
            end
        end

        function pollStatusFile(app)
            if ~isfile(app.StatusFile); return; end
            try
                fid    = fopen(app.StatusFile, 'r');
                status = strtrim(fgetl(fid));
                fclose(fid);
                if ~isempty(status) && ischar(status)
                    app.handleFaceStatus(status);
                end
            catch e
                app.updateDisplay(['Error: ' e.message]);
            end
        end

        function handleArduinoMessage(app, msg)
            if startsWith(msg, 'STATE:')
                state = extractAfter(msg, 'STATE:');
                app.updateSystemState(state);
                if strcmpi(state, 'MENU')
                    app.setMode('MENU');
                elseif strcmpi(state, 'DISARMED')
                    app.setMode('MENU');
                elseif strcmpi(state, 'DIAGNOSTIC')
                  %  app.setMode('MENU');  % reuse menu mode, diagnostic menu items come via MENU: messages
                else
                    app.setMode('PIN');
                end

            elseif startsWith(msg, 'SENSOR_LIST:')
                parts = strsplit(msg, ':');
                if numel(parts) >= 3
                    app.SensorList{end+1} = parts{2};
                    app.SensorCount = length(app.SensorList);
                end
            elseif strcmp(msg, 'PROMPT:ENTER_PIN')
                app.setMode('PIN');
                app.updateDisplay('Enter PIN:');
                
            elseif strcmp(msg, 'PROMPT:SELECT_SENSOR_TO_DISABLE')
                lines = {};
                for i = 1:length(app.SensorList)
                    lines{end+1} = [num2str(i) '. ' app.SensorList{i}];
                end
                lines{end+1} = 'Press number + ENT';
                app.DisplayLabel.Text = strjoin(lines, newline);
                app.setMode('SENSOR');

            elseif startsWith(msg, 'LOGS:START')
                % Clear any previous log display
                app.LogLines = {};

            elseif startsWith(msg, 'LOG:')
                % Accumulate log lines
                app.LogLines{end+1} = extractAfter(msg, 'LOG:');

            elseif strcmp(msg, 'LOGS:END')
                % All logs received - display them
                if isempty(app.LogLines)
                    app.DisplayLabel.Text = 'No log entries yet';
                else
                    % Make sure all entries are char, then join
                    logText = '';
                    for i = 1:length(app.LogLines)
                        if i == 1
                            logText = char(app.LogLines{i});
                        else
                            logText = [logText newline char(app.LogLines{i})];
                        end
                    end
                    app.DisplayLabel.Text = logText;
                end
                app.setMode('LOGS');


            elseif startsWith(msg, 'PROMPT:')
                prompt = strrep(extractAfter(msg, 'PROMPT:'), '_', ' ');
                app.updateDisplay(prompt);


            elseif startsWith(msg, 'MSG:')
                content = extractAfter(msg, 'MSG:');
                app.updateDisplay(content);
                % If PIN changed, force re-authentication
                if strcmp(content, 'PIN changed successfully')
                    try
                        fid = fopen(app.PINFile, 'w');
                        fprintf(fid, '%s', app.NewPIN);   
                        fclose(fid);
                        app.NewPIN = '';                  % clear after writing
                    catch
                    end
                    pause(1.5);
                    app.setMode('PIN');
                end

            elseif startsWith(msg, 'ALARM:')
                app.updateDisplay('!! ALARM TRIGGERED !!');
                app.updateSystemState('ALARM');

            elseif startsWith(msg, 'MENU:')
                app.handleMenuMessage(msg);

            elseif startsWith(msg, 'TIMER:')
                secs = extractAfter(msg, 'TIMER:');
                app.updateDisplay(['Enter PIN — ' secs 's remaining']);

            elseif startsWith(msg, 'SENSOR:')
                app.updateDisplay(['Sensor: ' extractAfter(msg, 'SENSOR:')]);

            elseif startsWith(msg, 'PIR:')
                app.updateDisplay('Motion detected — scanning face...');
                app.updateFaceStatus('Scanning...');
                app.FaceStatusLabel.FontColor = [1 0.6 0];
                app.writeCommand('TRIGGER');

            elseif startsWith(msg, 'NOTIFY:')
                app.updateDisplay(['ALERT: ' extractAfter(msg, 'NOTIFY:')]);
            end
   
        end

        function handleMenuMessage(app, msg)
            persistent menuLines
            if strcmp(msg, 'MENU:START')
                menuLines = {};
            elseif strcmp(msg, 'MENU:END')
                app.DisplayLabel.Text = strjoin(menuLines, newline);
                menuLines = {};
            else
                parts = strsplit(msg, ':');
                if numel(parts) >= 3
                    menuLines{end+1} = [parts{2} '. ' parts{3}];
                end
            end
        end

        function handleFaceStatus(app, status)
            if startsWith(status, 'AUTHORIZED:')
                name = extractAfter(status, 'AUTHORIZED:');
                app.updateFaceStatus(['Authorised: ' name]);
                app.FaceStatusLabel.FontColor = [0.2 0.8 0.4];
                app.sendToArduino('FACE:AUTHORIZED');
            elseif strcmp(status, 'UNAUTHORIZED')
                app.updateFaceStatus('Unauthorised face detected');
                app.FaceStatusLabel.FontColor = [0.9 0.3 0.3];
                app.sendToArduino('FACE:UNAUTHORIZED');
            elseif strcmp(status, 'SCANNING')
                app.updateFaceStatus('Scanning...');
                app.FaceStatusLabel.FontColor = [1 0.6 0];
            elseif strcmp(status, 'IDLE')
                app.updateFaceStatus('Facial recognition idle');
                app.FaceStatusLabel.FontColor = [0.5 0.5 0.5];
            elseif strcmp(status, 'CAPTURING')
                app.updateFaceStatus('Capturing face...');
                app.FaceStatusLabel.FontColor = [1 0.6 0];
            elseif strcmp(status, 'OFFLINE')
                app.updateFaceStatus('Face recognition offline');
                app.FaceStatusLabel.FontColor = [0.9 0.3 0.3];
            end
        end

        function updateDisplay(app, msg)
            app.DisplayLabel.Text = msg;
        end

        function updateSystemState(app, state)
            app.SystemState           = state;
            app.SystemStateLabel.Text = ['SYSTEM: ' upper(state)];
            switch upper(state)
                case 'MENU'
                    app.SystemStateLabel.FontColor = [0.2 0.8 0.4];
                case 'ARMED'
                    app.SystemStateLabel.FontColor = [0.2 0.8 0.4];
                case {'ALARM', 'ALARM_TRIGGERED'}
                    app.SystemStateLabel.FontColor = [0.9 0.3 0.3];
                case 'DISARMED'
                    app.SystemStateLabel.FontColor = [1 0.75 0.1];
                case 'DIAGNOSTIC'
                    app.SystemStateLabel.FontColor = [0.3 0.7 1];
                case 'SHUTDOWN'
                    app.SystemStateLabel.FontColor = [0.6 0.6 0.6];
                otherwise
                    app.SystemStateLabel.FontColor = [0.5 0.5 0.5];
            end
        end

        function updateFaceStatus(app, msg)
            app.FaceStatusLabel.Text = msg;
        end
        
        function updatePINDots(app)

            switch app.CurrentMode

                case 'PIN'
                    app.maxLen = 4;

                case {'MENU'}
                    app.maxLen = 1;

                case 'SENSOR'
                    app.maxLen = 1;

                case 'LOGS'
                    app.PINDotsLabel.Text = '';

                case 'CHANGE_PIN'
                    app.maxLen = 4;

                case 'GRACE_PERIOD'
                    app.maxLen = 2;

            end

            filled = repmat('● ', 1, length(app.CurrentPIN));
            empty  = repmat('○ ', 1, app.maxLen - length(app.CurrentPIN));

            app.PINDotsLabel.Text = strtrim([filled empty]);
        end

        function writeCommand(app, cmd)
            try
                fid = fopen(app.CommandFile, 'w');
                fprintf(fid, '%s', cmd);
                fclose(fid);
            catch 
            end
        end
        
        function keypadPressed(app, digit)

            switch app.CurrentMode
                case 'PIN'
                    app.maxLen = 4;

                case {'MENU'}
                    app.maxLen = 1;

                case 'GRACE_PERIOD'
                    app.maxLen = 2;
            end


            if length(app.CurrentPIN) < app.maxLen
                app.CurrentPIN = [app.CurrentPIN digit];
                app.updatePINDots();
            end
        end

        function handleMenuSelection(app, digit)
            switch digit
                case '1'
                    name = app.promptName('Add Facial Profile', ...
                        'Enter name for new profile:');
                    if ~isempty(name)
                        app.writeCommand(['ADD:' name]);
                        app.updateDisplay(['Adding: ' name ' — look at camera']);
                        app.sendToArduino('CMD:ADD_PROFILE');
                    end
                case '2'
                    name = app.promptName('Remove Profile', ...
                        'Enter name to remove:');
                    if ~isempty(name)
                        sel = uiconfirm(app.UIFigure, ...
                            ['Remove profile: ' name '?'], ...
                            'Confirm', ...
                            'Options', {'Remove','Cancel'}, ...
                            'DefaultOption', 2);
                        if strcmp(sel, 'Remove')
                            app.writeCommand(['REMOVE:' name]);
                            app.sendToArduino(['REMOVE_USER:' name]);
                            app.updateDisplay(['Removed: ' name]);
                        end
                    end
                case '3'
                    app.SensorList = {};
                    app.SensorCount = 0;
                    app.sendToArduino('CMD:DISABLE_SENSOR');
                    app.updateDisplay('Fetching sensors...');
                   
                case '4'
                    app.sendToArduino('CMD:VIEW_LOGS');
                    app.updateDisplay('Fetching logs...');
                case '5'
                    app.setMode('PIN');
                    app.updateDisplay('Enter PIN....');
                case '6'
                    app.OldPIN = '';
                    app.setMode('CHANGE_PIN');
                    app.sendToArduino('CMD:CHANGE_PIN');
                    % app.updateDisplay('Enter current PIN then new PIN');

                case '7'
                    app.setMode('GRACE_PERIOD');
                    app.updateDisplay('Enter grace period in seconds :');

                otherwise
                    app.sendToArduino(['PIN:' app.CurrentPIN]);
            end
        end

        function name = promptName(~, title, prompt)
            d = dialog('Position', [400 400 320 160], 'Name', title);
            d.Color = [0.12 0.12 0.12];
            uicontrol('Parent', d, 'Style', 'text', ...
                'Position', [20 105 280 30], 'String', prompt, ...
                'ForegroundColor', [1 1 1], ...
                'BackgroundColor', [0.12 0.12 0.12], 'FontSize', 11);
            txt = uicontrol('Parent', d, 'Style', 'edit', ...
                'Position', [20 68 280 30], 'FontSize', 12);
            name = '';
            uicontrol('Parent', d, 'Style', 'pushbutton', ...
                'Position', [20 20 130 35], 'String', 'Confirm', ...
                'FontSize', 11, 'BackgroundColor', [0.15 0.45 0.25], ...
                'ForegroundColor', [1 1 1], ...
                'Callback', @(~,~) confirmCb());
            uicontrol('Parent', d, 'Style', 'pushbutton', ...
                'Position', [165 20 130 35], 'String', 'Cancel', ...
                'FontSize', 11, 'BackgroundColor', [0.5 0.2 0.2], ...
                'ForegroundColor', [1 1 1], ...
                'Callback', @(~,~) delete(d));
            uiwait(d);
            function confirmCb()
                name = strtrim(txt.String);
                delete(d);
            end
        end

        function UIFigureCloseRequest(app, ~)
            app.stopTimers();
            if app.IsConnected
                try delete(app.SerialObj); catch; end
            end
            delete(app);
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initApp(app)
          appDir          = fileparts(mfilename('fullpath'));
          app.StatusFile  = fullfile(appDir, 'status.txt');
          app.CommandFile = fullfile(appDir, 'command.txt');
          app.PINFile     = fullfile(appDir, 'pin.txt');
          app.GracePeriodFile = fullfile(appDir, 'graceperiod.txt');
          app.updateDisplay('Connect to Arduino to begin');
          app.updateSystemState('DISCONNECTED');
          app.updateFaceStatus('Facial recognition idle');
          app.updatePINDots();  
        end

        % Button pushed function: Btn1
        function Btn1Pushed(app, event)
            app.keypadPressed('1');
        end

        % Button pushed function: Btn2
        function Btn2Pushed(app, event)
            app.keypadPressed('2');
        end

        % Button pushed function: Btn3
        function Btn3Pushed(app, event)
            app.keypadPressed('3');
        end

        % Button pushed function: Btn4
        function Btn4Pushed(app, event)
            app.keypadPressed('4');
        end

        % Button pushed function: Btn5
        function Btn5Pushed(app, event)
            app.keypadPressed('5');
        end

        % Button pushed function: Btn6
        function Btn6Pushed(app, event)
            app.keypadPressed('6');
        end

        % Button pushed function: Btn7
        function Btn7Pushed(app, event)
            app.keypadPressed('7');
        end

        % Button pushed function: Btn8
        function Btn8Pushed(app, event)
            app.keypadPressed('8');
        end

        % Button pushed function: Btn9
        function Btn9Pushed(app, event)
            app.keypadPressed('9');
        end

        % Button pushed function: Btn0
        function Btn0Pushed(app, event)
            app.keypadPressed('0');
        end

        % Button pushed function: BtnClear
        function BtnClearPushed(app, event)
            if strcmp(app.CurrentMode, 'PIN') || strcmp(app.CurrentMode, 'ACTION')
                % Short press - just clear
                app.CurrentPIN = '';
                app.updatePINDots();
                app.updateDisplay('PIN cleared');
                return;
            end

            if strcmp(app.CurrentMode, 'MENU')
                % In menu mode CLR just clears selection
                app.CurrentPIN = '';
                app.updatePINDots();
                return;
            end
        end

        % Button pushed function: BtnEnter
        function BtnEnterPushed(app, event)

            switch app.CurrentMode
                case 'PIN'
                    if length(app.CurrentPIN) ~= 4
                        app.updateDisplay('PIN must be 4 digits');
                        return;
                    end
                    app.sendToArduino(['PIN:' app.CurrentPIN]);
                    app.updateDisplay('Checking PIN...');
                    app.CurrentPIN = '';
                    app.updatePINDots();

                case 'MENU'
                    if ~isempty(app.CurrentPIN)
                        app.handleMenuSelection(app.CurrentPIN(1));
                    end
                    app.CurrentPIN = '';
                    app.updatePINDots();

                case 'SENSOR'
                    if ~isempty(app.CurrentPIN)
                        idx = str2double(app.CurrentPIN);
                        if idx >= 1 && idx <= app.SensorCount
                            sensorName = app.SensorList{idx};
                            app.sendToArduino(['CMD:TOGGLE_SENSOR:' sensorName]);
                            app.updateDisplay([sensorName ' toggled']);
                            app.SensorList = {};
                            app.SensorCount = 0;
                            app.setMode('MENU');
                            app.sendToArduino('CMD:MENU');
                        else
                            app.updateDisplay('Invalid selection');
                        end
                        app.CurrentPIN = '';
                        app.updatePINDots();
                    end

                case 'CHANGE_PIN'
                    if length(app.CurrentPIN) == 4
                        if isempty(app.OldPIN)
                            app.OldPIN = app.CurrentPIN;
                            app.CurrentPIN = '';
                            app.updatePINDots();
                            app.updateDisplay('Enter new PIN [DONT USE 0000]: ');
                        else
                            app.NewPIN = app.CurrentPIN;   % cache new PIN before clearing
                            app.sendToArduino(['CMD:CHANGE_PIN_VERIFY:' app.OldPIN ':' app.CurrentPIN]);
                            app.OldPIN = '';
                            app.CurrentPIN = '';
                            app.updatePINDots();
                        end
                    else
                        app.updateDisplay('PIN must be 4 digits');
                    end
                case 'GRACE_PERIOD'
                    if ~isempty(app.CurrentPIN)
                        grace = app.CurrentPIN;
                        % Save to file
                        try
                            fid = fopen(app.GracePeriodFile, 'w');
                            fprintf(fid, '%s', grace);
                            fclose(fid);
                        catch
                        end
                        % Send to Arduino
                        app.sendToArduino(['GRACE_SET:' grace]);
                        app.updateDisplay(['Grace period set to ' grace 's']);
                        app.CurrentPIN = '';
                        app.updatePINDots();
                    end
            end

        end

        % Button pushed function: BtnDisconnect
        function BtnDisconnectPushed(app, event)
            app.stopTimers();
            try delete(app.SerialObj); catch; end
            app.IsConnected                 = false;
            app.SerialStatusLabel.Text      = '● Not connected';
            app.SerialStatusLabel.FontColor = [0.9 0.3 0.3];
            app.BtnConnect.Enable           = 'on';
            app.BtnDisconnect.Enable        = 'off';
            app.updateSystemState('DISCONNECTED');
            app.updateDisplay('Disconnected');
          
          
        end

        % Button pushed function: BtnConnect
        function BtnConnectPushed(app, event)
            try
                port           = app.COMPortField.Value;
                app.SerialObj  = serialport(port, 9600);
                configureTerminator(app.SerialObj, "LF");
                app.IsConnected = true;
                app.SerialStatusLabel.Text      = ['● ' port];
                app.SerialStatusLabel.FontColor = [0.2 0.8 0.4];
                app.BtnConnect.Enable           = 'off';
                app.BtnDisconnect.Enable        = 'on';
                app.updateSystemState('CONNECTED');
                app.updateDisplay('Connected — waiting for system...');
                app.startTimers();
                % Send stored PIN to Arduino
                if isfile(app.PINFile)
                    storedPIN = strtrim(fileread(app.PINFile));

                    if ~isempty(storedPIN)
                        pause(2); % wait for Arduino to boot/settle
                        app.sendToArduino(['PIN_SET:' storedPIN]);
                        disp(['PIN read from file: ' storedPIN]);
                    end
                end
                % Send stored grace period to Arduino
                if isfile(app.GracePeriodFile)
                    storedGrace = strtrim(fileread(app.GracePeriodFile));
                    if ~isempty(storedGrace)
                        pause(2);
                        app.sendToArduino(['GRACE_SET:' storedGrace]);
                     
                    end
                end
            catch e
                app.updateDisplay(['Connection failed: ' e.message]);
                app.SerialStatusLabel.Text      = '● Not connected';
                app.SerialStatusLabel.FontColor = [0.9 0.3 0.3];
            end
        end

        % Button pushed function: BtnBack
        function BtnBackPushed(app, event)
            app.setMode('MENU');
            app.sendToArduino('CMD:MENU');            
            app.LogLines = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SystemStateLabel = uilabel(app.Panel);
            app.SystemStateLabel.Text = 'SYSTEM: DISCONNECTED';
            app.FaceStatusLabel = uilabel(app.Panel);
            app.FaceStatusLabel.Text = 'Facial recognition idle';
            app.DisplayLabel = uilabel(app.Panel);
            app.DisplayLabel.Text = 'Connect to Arduino to begin';
            app.PINDotsLabel = uilabel(app.Panel);
            app.PINDotsLabel.Text = '○ ○ ○ ○';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Btn1 = uibutton(app.Panel_2, 'push');
            app.Btn1.ButtonPushedFcn = createCallbackFcn(app, @Btn1Pushed, true);
            app.Btn1.Text = '1';
            app.Btn2 = uibutton(app.Panel_2, 'push');
            app.Btn2.ButtonPushedFcn = createCallbackFcn(app, @Btn2Pushed, true);
            app.Btn2.Text = '2';
            app.Btn3 = uibutton(app.Panel_2, 'push');
            app.Btn3.ButtonPushedFcn = createCallbackFcn(app, @Btn3Pushed, true);
            app.Btn3.Text = '3';
            app.Btn4 = uibutton(app.Panel_2, 'push');
            app.Btn4.ButtonPushedFcn = createCallbackFcn(app, @Btn4Pushed, true);
            app.Btn4.Text = '4';
            app.Btn5 = uibutton(app.Panel_2, 'push');
            app.Btn5.ButtonPushedFcn = createCallbackFcn(app, @Btn5Pushed, true);
            app.Btn5.Text = '5';
            app.Btn6 = uibutton(app.Panel_2, 'push');
            app.Btn6.ButtonPushedFcn = createCallbackFcn(app, @Btn6Pushed, true);
            app.Btn6.Text = '6';
            app.Btn7 = uibutton(app.Panel_2, 'push');
            app.Btn7.ButtonPushedFcn = createCallbackFcn(app, @Btn7Pushed, true);
            app.Btn7.Text = '7';
            app.Btn8 = uibutton(app.Panel_2, 'push');
            app.Btn8.ButtonPushedFcn = createCallbackFcn(app, @Btn8Pushed, true);
            app.Btn8.Text = '8';
            app.Btn9 = uibutton(app.Panel_2, 'push');
            app.Btn9.ButtonPushedFcn = createCallbackFcn(app, @Btn9Pushed, true);
            app.Btn9.Text = '9';
            app.BtnClear = uibutton(app.Panel_2, 'push');
            app.BtnClear.ButtonPushedFcn = createCallbackFcn(app, @BtnClearPushed, true);
            app.BtnClear.Text = {'CLR'; ''};
            app.Btn0 = uibutton(app.Panel_2, 'push');
            app.Btn0.ButtonPushedFcn = createCallbackFcn(app, @Btn0Pushed, true);
            app.Btn0.Text = '0';
            app.BtnEnter = uibutton(app.Panel_2, 'push');
            app.BtnEnter.ButtonPushedFcn = createCallbackFcn(app, @BtnEnterPushed, true);
            app.BtnEnter.Text = 'ENT';
            app.BtnBack = uibutton(app.Panel_2, 'push');
            app.BtnBack.ButtonPushedFcn = createCallbackFcn(app, @BtnBackPushed, true);
            app.BtnBack.Text = 'BACK';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SERIALCONNECTIONLabel = uilabel(app.Panel_3);
            app.SERIALCONNECTIONLabel.Text = 'SERIAL CONNECTION';
            app.COMPortLabel = uilabel(app.Panel_3);
            app.COMPortLabel.Text = 'COM Port';
            app.EditFieldLabel = uilabel(app.Panel_3);
            app.EditFieldLabel.Text = '';
            app.COMPortField = uieditfield(app.Panel_3, 'text');
            app.COMPortField.Value = 'COM17';
            app.BtnConnect = uibutton(app.Panel_3, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectPushed, true);
            app.BtnConnect.Text = 'Connect';
            app.BtnDisconnect = uibutton(app.Panel_3, 'push');
            app.BtnDisconnect.ButtonPushedFcn = createCallbackFcn(app, @BtnDisconnectPushed, true);
            app.BtnDisconnect.Text = 'Disconnect';
            app.SerialStatusLabel = uilabel(app.Panel_3);
            app.SerialStatusLabel.Text = '● Not connected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurglarAlarmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end